classdef C22037039_COURSEWORK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HideRevealAllIntermediateConstructionsButton  matlab.ui.control.Button
        HideRevealLinesButton   matlab.ui.control.Button
        HideRevealPointsButton  matlab.ui.control.Button
        RunConstructionButton   matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        plottedPoint % Description
    end
    
    methods (Access = private)


        function plotPoint(app, x, y)
            % Plot the point on the axes
            hold(app.UIAxes, 'on'); % Retain previous plots
            plot(app.UIAxes, x, y, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r'); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Get the mouse click location in data units
            clickCoords = app.UIAxes.CurrentPoint;
            x = clickCoords(1, 1); % X-coordinate of the click
            y = clickCoords(1, 2); % Y-coordinate of the click
            plotPoint(app, x, y);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PLOTTING CIRCLES')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RunConstructionButton = uibutton(app.UIFigure, 'push');
            app.RunConstructionButton.Text = 'Run Construction';
            app.HideRevealPointsButton = uibutton(app.UIFigure, 'push');
            app.HideRevealPointsButton.Text = 'Hide/Reveal Points';
            app.HideRevealLinesButton = uibutton(app.UIFigure, 'push');
            app.HideRevealLinesButton.Text = 'Hide/Reveal Lines';
            app.HideRevealAllIntermediateConstructionsButton = uibutton(app.UIFigure, 'push');
            app.HideRevealAllIntermediateConstructionsButton.Text = {'Hide/Reveal '; 'All Intermediate Constructions'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = C22037039_COURSEWORK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end